classdef final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        HumiditylevelEditField       matlab.ui.control.EditField
        HumiditylevelEditFieldLabel  matlab.ui.control.Label
        TemperatureEditField         matlab.ui.control.EditField
        TemperatureEditFieldLabel    matlab.ui.control.Label
        MotorspeedEditField          matlab.ui.control.EditField
        MotorspeedEditFieldLabel     matlab.ui.control.Label
        DistancecmEditField          matlab.ui.control.EditField
        DistancecmEditFieldLabel     matlab.ui.control.Label
        Headlight2Lamp               matlab.ui.control.Lamp
        Headlight2LampLabel          matlab.ui.control.Label
        Headlight1Lamp               matlab.ui.control.Lamp
        Headlight1LampLabel          matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        DistanceLED3Lamp             matlab.ui.control.Lamp
        DistanceLED3LampLabel        matlab.ui.control.Label
        DistanceLED2Lamp             matlab.ui.control.Lamp
        DistanceLED2LampLabel        matlab.ui.control.Label
        DistanceLED1Lamp             matlab.ui.control.Lamp
        DistanceLED1LampLabel        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

% Set up communications
arduino = serialport("COM3",9600);
configureTerminator(arduino, "CR/LF");
arduino.Terminator;
pause(1)
for K=0:250
flush(arduino)
write(arduino,2,'string')
b = read(arduino,4, 'string');
a=str2double(b);
if(a>60)
    b = "60";
end
c = num2str(a*100/255);
if((a*100/255) >100)
    c= "100";
elseif((a*100/255))< 5
    c= "0";
end
app.MotorspeedEditField.Value = c;
app.DistancecmEditField.Value = b;
pause(1)
%app.TemperatureEditField.Value = a;
if( (a >= 30))
    app.DistanceLED1Lamp.Color ='g';
    app.DistanceLED2Lamp.Color ='k';
    app.DistanceLED3Lamp.Color ='k';
elseif((a > 7) && (a < 30))
    app.DistanceLED1Lamp.Color ='g';
    app.DistanceLED2Lamp.Color ='yellow';
    app.DistanceLED3Lamp.Color ='k';
elseif ((a<7) || ( num2str(a) == "NaN"))
    app.DistanceLED1Lamp.Color ='g';
    app.DistanceLED2Lamp.Color ='yellow';
    app.DistanceLED3Lamp.Color ='red';
else
    app.DistanceLED1Lamp.Color ='k';
    app.DistanceLED2Lamp.Color ='k';
    app.DistanceLED3Lamp.Color ='k';
end
end
%a=read(arduino,4,'string');
flush(arduino)
delete(arduino)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DistanceLED1LampLabel = uilabel(app.UIFigure);
            app.DistanceLED1LampLabel.Text = 'Distance LED1';
            app.DistanceLED1Lamp = uilamp(app.UIFigure);
            app.DistanceLED2LampLabel = uilabel(app.UIFigure);
            app.DistanceLED2LampLabel.Text = 'Distance LED2';
            app.DistanceLED2Lamp = uilamp(app.UIFigure);
            app.DistanceLED3LampLabel = uilabel(app.UIFigure);
            app.DistanceLED3LampLabel.Text = 'Distance LED3';
            app.DistanceLED3Lamp = uilamp(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Headlight1LampLabel = uilabel(app.UIFigure);
            app.Headlight1LampLabel.Text = 'Headlight 1';
            app.Headlight1Lamp = uilamp(app.UIFigure);
            app.Headlight2LampLabel = uilabel(app.UIFigure);
            app.Headlight2LampLabel.Text = 'Headlight 2';
            app.Headlight2Lamp = uilamp(app.UIFigure);
            app.DistancecmEditFieldLabel = uilabel(app.UIFigure);
            app.DistancecmEditFieldLabel.Text = 'Distance(cm)';
            app.DistancecmEditField = uieditfield(app.UIFigure, 'text');
            app.MotorspeedEditFieldLabel = uilabel(app.UIFigure);
            app.MotorspeedEditFieldLabel.Text = 'Motor speed';
            app.MotorspeedEditField = uieditfield(app.UIFigure, 'text');
            app.TemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureEditFieldLabel.Text = 'Temperature ';
            app.TemperatureEditField = uieditfield(app.UIFigure, 'text');
            app.TemperatureEditField.Value = '50';
            app.HumiditylevelEditFieldLabel = uilabel(app.UIFigure);
            app.HumiditylevelEditFieldLabel.Text = 'Humidity level';
            app.HumiditylevelEditField = uieditfield(app.UIFigure, 'text');
            app.HumiditylevelEditField.Value = '26';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end